classdef animazione < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        TempodirappresentazioneEditField  matlab.ui.control.NumericEditField
        TempodirappresentazioneEditFieldLabel  matlab.ui.control.Label
        AvviaanimazioneButton  matlab.ui.control.Button
        ConcentrazionedelfarmacoEditField  matlab.ui.control.NumericEditField
        ConcentrazionedelfarmacoEditFieldLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: AvviaanimazioneButton, UIAxes
        function AvviaanimazioneButtonPushed(app, event)
            x1 = 100;
            x2 = 400;

            % tempo iniziale (t0) e finale (tf) per la risoluzione delle equazioni differenziali
            % e condizioni iniziali del sistema (x0)
            x0 = [x1, x2];
            t0 = 0;
            tf = app.TempodirappresentazioneEditField.Value;

            % utilizzo della funzione ode45 per risolvere le equazioni differenziali descritte dalla funzione fun.
            [t,x] = ode45(@(t,x) fun(t,x), [t0, tf], x0);

            % grafico che visualizza il numero di cellule suscettibili e resistenti nel tempo
            s1 = scatter(app.UIAxes, t(1),x(1,1),'c','filled');
            hold(app.UIAxes);
            s2 = scatter(app.UIAxes, t(1),x(1,2),'b','filled');
            legend(app.UIAxes, 'Cellule suscettibili','Cellule resistenti');

            % ciclo for per aggiornare continuamente il grafico nel tempo
            for i=5:length(t)
                s1.XData = t(1:i);
                s1.YData = x(1:i,1);
                s2.XData = t(1:i);
                s2.YData = x(1:i,2);
                drawnow;
            end

            % funzione in cui vengono definiti i parametri dati, e implementate le
            % equazioni differenziali
            function dxdt = fun(t,x)
                rs = 1.7; % tassi di riproduzione delle cellule suscettibili
                rr = 1.4; % e resistenti
                K = 500; % numero massimo di cellule
                ms = 0.95; % mortalità delle cellule suscettibili
                mr = 0.05; % e resistenti
                cf = app.ConcentrazionedelfarmacoEditField.Value; % concentrazione del farmaco

                % costanti
                gamma = 0.2;
                beta = 0.8;
                alfa = 0.5;

                dxdt = [-rs*log((x(1) + x(2))/K)*x(1) - ms*cf*x(1) - beta*x(1) + gamma*x(2) - alfa*cf*x(1);
                    -rr*log((x(1) + x(2))/K)*x(2) - mr*cf*x(2) + beta*x(1) - gamma*x(2) + alfa*cf*x(1)];

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Evoluzione delle cellule')
            xlabel(app.UIAxes, 'Tempo')
            ylabel(app.UIAxes, 'Numero di cellule')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @AvviaanimazioneButtonPushed, true);
            colormap(app.UIAxes, 'cool')
            app.Panel = uipanel(app.UIFigure);
            app.ConcentrazionedelfarmacoEditFieldLabel = uilabel(app.Panel);
            app.ConcentrazionedelfarmacoEditFieldLabel.Text = 'Concentrazione del farmaco';
            app.ConcentrazionedelfarmacoEditField = uieditfield(app.Panel, 'numeric');
            app.ConcentrazionedelfarmacoEditField.Limits = [0 1000];
            app.ConcentrazionedelfarmacoEditField.Value = 20;
            app.AvviaanimazioneButton = uibutton(app.Panel, 'push');
            app.AvviaanimazioneButton.ButtonPushedFcn = createCallbackFcn(app, @AvviaanimazioneButtonPushed, true);
            app.AvviaanimazioneButton.Text = 'Avvia animazione';
            app.TempodirappresentazioneEditFieldLabel = uilabel(app.Panel);
            app.TempodirappresentazioneEditFieldLabel.Text = 'Tempo di rappresentazione';
            app.TempodirappresentazioneEditField = uieditfield(app.Panel, 'numeric');
            app.TempodirappresentazioneEditField.Limits = [1 800];
            app.TempodirappresentazioneEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = animazione

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end